classdef RubberNutrile_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        uipanel1     matlab.ui.container.Panel
        text2_2      matlab.ui.control.Label
        popupmenu1   matlab.ui.control.DropDown
        pushbutton6  matlab.ui.control.Button
        back         matlab.ui.control.Button
        uipanel2     matlab.ui.container.Panel
        text8        matlab.ui.control.Label
        text7        matlab.ui.control.Label
        text6        matlab.ui.control.Label
        text5        matlab.ui.control.Label
        text4        matlab.ui.control.Label
        Hole         matlab.ui.control.Label
        pushbutton3  matlab.ui.control.Button
        text2        matlab.ui.control.Label
        pushbutton1  matlab.ui.control.Button
        axes1        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: pushbutton1
        function LoadButtonButtonPushed(app, event)
              % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %Clearing all previous image data and ressting the defect counters
             set(handles.text6,'string',  "0");
             set(handles.text7,'string',  "0");
             set(handles.text8,'string',  "0");
            
            
            [File_Name, Path_Name] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Image Files (*.jpg, *.png, *.bmp, *.tif)'},'Select image');
            fullname = fullfile(Path_Name,File_Name);
            im = imread(fullname);
            axes(handles.axes1);
            imshow(im, 'Parent', handles.axes1);
        end

        % Button pushed function: back
        function backButtonPushed(app, event)
            % --- Executes on button press in back.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to back (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Close the first GUI
                close();
            
            % Create and display the second GUI
            mainMenu_App;
        end

        % Button pushed function: pushbutton3
        function DetectButton3ButtonPushed(app, event)
                        % --- Executes on button press in pushbutton3.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            input = getimage(handles.axes1);
            
            % Get the image data from the object
            % im = get(input, 'CData');
            
            
            switch get(handles.popupmenu1, 'value')
                case 1
                    RubberNitrileMissingFingerDetector(input);
                    count = load(fullfile(pwd, 'RNvariables.mat'));
                    set(handles.text6,'string', num2str(numel(count.LMissingFinger)));
                    
                    axes(handles.axes1);
                    imshow(input,'Parent', handles.axes1); 
                    % Draw bounding boxes around large stitches on the original image
                    for i = 1:numel(count.LMissingFinger)
                    bbox = count.LMissingFinger(i).BoundingBox;
                    rectangle(handles.axes1,'Position', bbox, 'EdgeColor', 'r', 'LineWidth', 2);
                    end

                case 2
                    RubberNitrileHolesDetector(input);
                    count = load(fullfile(pwd, 'RNvariables.mat'));
                    set(handles.text7,'string',  num2str(numel(count.large_holes)));

                    axes(handles.axes1);
                    imshow(input,'Parent', handles.axes1); 
                    for i = 1:numel(count.large_holes)
                        bbox = count.large_holes(i).BoundingBox;
                        rectangle(handles.axes1,'Position', bbox, 'EdgeColor', 'r', 'LineWidth', 2);
                    end
                    
                case 3
                    RubberNitrileStainDetector(input);
                    count = load(fullfile(pwd, 'RNvariables.mat'));
                    set(handles.text8,'string', num2str(numel(count.large_stains)));

                    for i = 1:numel(count.large_stains)
                        bbox = count.large_stains(i).BoundingBox;
                        rectangle(handles.axes1,'Position', bbox, 'EdgeColor', 'r', 'LineWidth', 2);
                    end
            end
        end

        % Button pushed function: pushbutton6
        function ShowMaskbutton6ButtonPushed(app, event)
             % --- Executes on button press in pushbutton3.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            switch get(handles.popupmenu1, 'value')
                case 1
                    picture = load(fullfile(pwd, 'RNpic.mat'));
                    figure("Name","Masks");
                    subplot(131); imshow(picture.RNMF_glove_filtered_mask); title("FilledMask");
                    subplot(132); imshow(picture.RNMF_defect_mask); title("DefectMask");
                    subplot(133); imshow(picture.RN_missingfinger); title("Final Mask");
                   
                case 2
                    picture = load(fullfile(pwd, 'RNpic.mat'));
                    figure("Name","Masks");
                    subplot(131); imshow(picture.RNH_filledMask); title("FilledMask");
                    subplot(132); imshow(picture.RNH_DefectMask); title("DefectMask");
                    subplot(133); imshow(picture.RNH_hole); title("Final Mask");
                case 3
                    picture = load(fullfile(pwd, 'RNpic.mat'));
                    figure("Name","Masks");
                    subplot(131); imshow(picture.RNS_glove_mask); title("FilledMask");
                    subplot(132); imshow(picture.RNS_defect_mask); title("DefectMask");
                    subplot(133); imshow(picture.RNS_stain_mask); title("Final Mask");
                                
            end
        end

        % Value changed function: popupmenu1
        function popupmenu1ValueChanged(app, event)
            value = app.popupmenu1.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes1 = uiaxes(app.UIFigure);
            app.axes1.Tag = 'axes1';
            app.uipanel1 = uipanel(app.UIFigure);
            app.uipanel1.Title = 'Control Panel';
            app.uipanel1.Tag = 'uipanel1';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonButtonPushed, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Upload Image';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Rubber Nutrile Gloves';
            app.pushbutton3 = uibutton(app.uipanel1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @DetectButton3ButtonPushed, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'Apply Defects';
            app.uipanel2 = uipanel(app.uipanel1);
            app.uipanel2.Title = 'Defects Detected:';
            app.uipanel2.Tag = 'uipanel2';
            app.Hole = uilabel(app.uipanel2);
            app.Hole.Tag = 'Hole';
            app.Hole.Text = 'Missing Finger:';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Holes:';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Stain:';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = '0';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Tag = 'text7';
            app.text7.Text = '0';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = '0';
            app.back = uibutton(app.uipanel1, 'push');
            app.back.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.back.Tag = 'back';
            app.back.Text = 'Back';
            app.pushbutton6 = uibutton(app.uipanel1, 'push');
            app.pushbutton6.ButtonPushedFcn = createCallbackFcn(app, @ShowMaskbutton6ButtonPushed, true);
            app.pushbutton6.Tag = 'pushbutton6';
            app.pushbutton6.Text = 'Show Masks';
            app.popupmenu1 = uidropdown(app.uipanel1);
            app.popupmenu1.Items = {'Missing Finger', 'Hole', 'Stain'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1ValueChanged, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'Missing Finger';
            app.text2_2 = uilabel(app.uipanel1);
            app.text2_2.Tag = 'text2';
            app.text2_2.Text = ' Select:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RubberNutrile_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end